classdef CorrelationPlot_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        text_epochs_2     matlab.ui.control.Label
        text_time         matlab.ui.control.Label
        edit_epoch        matlab.ui.control.EditField
        text_epochs       matlab.ui.control.Label
        text_epoch_start  matlab.ui.control.Label
        text_epoch_end    matlab.ui.control.Label
        slider1           matlab.ui.control.Slider
        text_title        matlab.ui.control.Label
        axes_corr         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function edit_last_epochs_Callback(app, hObject, eventdata, handles) %#ok<INUSD>
            % new value in "plot last n epochs"
            slider1_Callback(app, event)
        end
        
        function edit_last_epochs_CreateFcn(app, hObject, eventdata, handles) %#ok<INUSD>
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit_last_epochs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CorrelationPlot_OpeningFcn(app, varargin)
            % --- Executes just before CorrelationPlot is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to CorrelationPlot (see VARARGIN)
            
            storeData = varargin{1};      	% struct
            satellites = varargin{2};       % struct
            settings = varargin{3};         % struct
            vec_epochs = varargin{4};       % vector of epochs
            
            % write time of epoch
            epoch_last = vec_epochs(end);   % last epoch
            time_sow = storeData.gpstime;      % vector with time of epochs in [sow]
            [~, hour, min, sec] = sow2dhms(time_sow(1));
            str_time = ['Time: ', sprintf('%02d', hour), ':' ,sprintf('%02d', min), ':' , sprintf('%02d', sec)];
            set(app.text_time, 'Text', str_time);

            % Plot correlation for first epoch
            axes(handles.axes_corr);
            cla             % clear last plot
            plotCovarianceAdjustment(storeData, satellites, settings, 1);
            
            % Set epoch end to the right side of the slider
            set(handles.text_epoch_end, 'String', sprintf('%d', epoch_last));
            
            % Choose default command line output for CorrelationPlot
            handles.output = hObject;
            
            % save everything which is needed in the future in struct handles
            handles.epochs = vec_epochs;
            handles.storeData = storeData;
            handles.satellites = satellites;
            handles.settings = settings;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: edit_epoch
        function edit_epoch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % manual input of epoch
            ep = get(handles.edit_epoch, 'String');
            ep = str2double(ep);                % get value of entered epoch
            
            vec_epochs = handles.epochs;        % vector with epochs
            no_epochs = numel(vec_epochs);      % number of epochs
            
            if ep > no_epochs           % entered epoch is too big
                ep = no_epochs;
                set(handles.edit_epoch, 'String', num2str(ep))
            end
            if ep < 1                   % entered epoch is too small
                ep = 1;
                set(handles.edit_epoch, 'String', num2str(ep))
            end
            
            slider_pos = ep/no_epochs;          % convert epoch into slider position
            handles.slider1.Value = slider_pos; % set slider to position
            slider1_Callback(app, event)   % refresh plot
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            % get everything what is needed from struct handles
            vec_epochs = handles.epochs;
            last_epoch = vec_epochs(end);
            storeData = handles.storeData;
            satellites = handles.satellites;
            settings = handles.settings;
            time_sow = storeData.gpstime;
            
            slider_pos = handles.slider1.Value;     % get position of slider
            idx = 1;
            if slider_pos ~= 0              % convert slider-position into epoch
                idx = round(slider_pos*last_epoch);
            end
            
            % plot the chosen epochs
            axes(handles.axes_corr);
            cla             % clear last plot
            plotCovarianceAdjustment(storeData, satellites, settings, idx);
            
            % write epoch in text-field
            set(handles.edit_epoch, 'String', sprintf('%d', vec_epochs(idx)));
            
            % write time of epoch
            [~, hour, min, sec] = sow2dhms(time_sow(idx));
            str_time = ['Time: ', sprintf('%02d', hour), ':' ,sprintf('%02d', min), ':' , sprintf('%02d', sec)];
            set(app.text_time, 'Text', str_time);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'CorrelationPlot';
            app.figure1.Tag = 'figure1';
            app.axes_corr = uiaxes(app.figure1);
            app.axes_corr.Tag = 'axes_corr';
            app.text_title = uilabel(app.figure1);
            app.text_title.Tag = 'text_title';
            app.text_title.Text = {'Correlation Plot of'; 'Estimated Parameters'};
            app.slider1 = uislider(app.figure1);
            app.slider1.Limits = [0 1];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.slider1.Tag = 'slider1';
            app.text_epoch_end = uilabel(app.figure1);
            app.text_epoch_end.Tag = 'text_epoch_end';
            app.text_epoch_end.Text = '9999999';
            app.text_epoch_start = uilabel(app.figure1);
            app.text_epoch_start.Tag = 'text_epoch_start';
            app.text_epoch_start.Text = '1';
            app.text_epochs = uilabel(app.figure1);
            app.text_epochs.Tag = 'text_epochs';
            app.text_epochs.Text = 'Epochs';
            app.edit_epoch = uieditfield(app.figure1, 'text');
            app.edit_epoch.ValueChangedFcn = createCallbackFcn(app, @edit_epoch_Callback, true);
            app.edit_epoch.Tag = 'edit_epoch';
            app.edit_epoch.Value = '0';
            app.text_time = uilabel(app.figure1);
            app.text_time.Tag = 'text_time';
            app.text_time.Text = 'Time';
            app.text_epochs_2 = uilabel(app.figure1);
            app.text_epochs_2.Text = 'Epoch:';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CorrelationPlot_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)CorrelationPlot_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end