classdef TropoPlot_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        pushbutton_histo     matlab.ui.control.Button
        checkbox_ZTD_model   matlab.ui.control.CheckBox
        checkbox_resets      matlab.ui.control.CheckBox
        pushbutton_download  matlab.ui.control.Button
        pushbutton_figure    matlab.ui.control.Button
        checkbox_IGS_ZTD     matlab.ui.control.CheckBox
        checkbox_ZHD_model   matlab.ui.control.CheckBox
        checkbox_ZWD_est     matlab.ui.control.CheckBox
        checkbox_ZWD_model   matlab.ui.control.CheckBox
        checkbox_ZTD         matlab.ui.control.CheckBox
        text5                matlab.ui.control.Label
        axes1                matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function handles = load_read_IGS_estimation(app, handles)
            % Download IGS estimation for current station from ftp-server and read the
            % data in
            % get date of current station
            date = handles.startdate;
            date = date(1,:);       % take first row of date for plotting from Multi Plot table
            jd = cal2jd_GT(date(1), date(2), date(3));
            % convert (from julian date) into other formats
            [doy, yyyy] = jd2doy_GT(jd);
            % download tropofile
            [tropofile, success] = DownloadTropoFile(handles.station, yyyy, doy);
            if ~success
                handles.checkbox_IGS_ZTD.Enable = 'off';
                return      % download failed
            end
            % read file
            tropodata = readTropoFile(tropofile, handles.station);
            tropodata(:,3) = tropodata(:,3) / 3600;     % convert to [hours]
            % save into handles
            handles.IGS_est = tropodata(:,3:4);
            handles.checkbox_IGS_ZTD.Enable = 'on';
        end
        
        function handles = plotTropoGUI(app, handles)
            % Plots estimated tropospheric zenith wet delay
            %
            % INPUT:
            %   hours           vector, time in hours from beginning of processing
            %   strXAxis        label for x-axis
            %   storeData       data from processing
            %   resets          vector, time of resets in hours
            % OUTPUT:
            %   []
            % using vline.m or hline.m (c) 2001, Brandon Kuczenski
            %
            % *************************************************************************
            
            hours = handles.h;
            label_x = handles.label_x;
            reset_h = handles.reset_h;
            
            axes(handles.axes1);
            cla             % clear last plot
            hold on
            leg_txt = {};
            
            if ~any([handles.checkbox_ZTD.Value, handles.checkbox_ZHD_model.Value, handles.checkbox_ZWD_model.Value, handles.checkbox_ZWD_est.Value, handles.checkbox_IGS_ZTD.Value, handles.checkbox_ZTD_model.Value])
                return
            end
            
            zhd_model = handles.zhd_model;
            zwd_model = handles.zwd_model;
            ztd_model = zhd_model + zwd_model;
            zwd_est = handles.zwd_est;
            
            y_min = []; y_max = [];
            
            % plot total troposphere zenith delay
            if handles.checkbox_ZTD.Value
                ztd = zhd_model+zwd_model+zwd_est;
                plot(hours, ztd, '.', 'Color', [.44 1 .44]);
                leg_txt{end+1} = 'ZTD';
                y_min = min([y_min; ztd]);
                y_max = max([y_max; ztd]);
            end
            % plot modeled ztd
            if handles.checkbox_ZTD_model.Value
                plot(hours, ztd_model, '.', 'Color', [.22 .22 .22]);
                leg_txt{end+1} = 'Modeled ZTD';
                y_min = min([y_min; ztd_model]);
                y_max = max([y_max; ztd_model]);
            end
            % plot modeled zhd
            if handles.checkbox_ZHD_model.Value
                plot(hours, zhd_model, '.', 'Color', [.77 .77 .77]);
                leg_txt{end+1} = 'Modeled ZHD';
                y_min = min([y_min; zhd_model]);
                y_max = max([y_max; zhd_model]);
            end
            % plot modeled zwd
            if handles.checkbox_ZWD_model.Value
                plot(hours, zwd_model, '.', 'Color', [.44 .44 .44]);
                leg_txt{end+1} = 'Modeled ZWD';
                y_min = min([y_min; zwd_model]);
                y_max = max([y_max; zwd_model]);
            end
            % plot estimated residual zwd
            if handles.checkbox_ZWD_est.Value
                plot(hours, zwd_est, '.', 'Color', [.18 0 1]);
                % plot black dots where no zwd was estimated
                nonzero = (zwd_est ~= 0);
                plot(hours(~nonzero), zwd_est(~nonzero), 'k.')
                leg_txt{end+1} = 'Estimated ZWD';
                y_min = min([y_min; zwd_est]);
                y_max = max([y_max; zwd_est]);
            end
            % plot ZTD estimation from IGS
            if ~isempty(handles.IGS_est) && handles.checkbox_IGS_ZTD.Value
                IGS_hours = handles.IGS_est(:,1);
                IGS_est = handles.IGS_est(:,2);
                plot(IGS_hours, IGS_est, '--', 'Color', [1 .44 .44], 'LineWidth', 2);
                leg_txt{end+1} = 'ZTD IGS';
                y_min = min([y_min; IGS_est]);
                y_max = max([y_max; IGS_est]);
            end
            
            % style
            title(['Troposphere Plot, ' handles.ff])
            xlabel(label_x)
            ylabel('Delay [m]')
            grid on;
            xlim([hours(1) hours(end)])
            Grid_Xoff_Yon()
            legend(leg_txt)
            dy = 0.05*abs(y_min);
            if y_min==0 && dy==0; y_min = -Inf; end
            if y_max==0 && dy==0; y_max =  Inf; end
            ylim([y_min-dy, y_max + dy])
            
            % save legend for creating a figure
            handles.leg_txt = leg_txt;
            
            % show resets with grid lines
            if ~isempty(reset_h) && handles.checkbox_resets.Value
                ax = gca;
                ax.XGrid = 'on';
                set(gca,'xtick', reset_h)
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function TropoPlot_OpeningFcn(app, varargin)
            % --- Executes just before TropoPlot is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to TropoPlot (see VARARGIN)
            
            % get input
            hours = varargin{1};
            label_x = varargin{2};
            storeData = varargin{3};
            reset_h = varargin{4};
            startdate = varargin{5};
            station = varargin{6};
            param = varargin{7};
            ff = varargin{8};
            
            % determine index of estimated zwd in storeData.param
            idx_zwd = 4;
            if isfield(storeData, 'ORDER_PARAM')
                idx_zwd = find(strcmp(storeData.ORDER_PARAM, 'zwd'));
            end
            
            % save variables for later to handles
            handles.h = hours;
            handles.reset_h = reset_h;
            handles.label_x = label_x;
            handles.startdate = startdate;
            handles.station = station;
            handles.zhd_model = storeData.zhd;      % modeled ZHD
            handles.zwd_model = storeData.zwd;      % modeled ZWD
            handles.zwd_est   = param(:,idx_zwd);    % estimated ZWD
            handles.IGS_est   = []; handles.checkbox_IGS_ZTD.Enable = 'off';
            handles.leg_txt   = [];
            handles.ff = ff;
            
            % check if IGS troposphere file already exists
            if ~isempty(handles.station)
                STAT = upper(handles.station);
                stat = lower(handles.station(1:4));
                % get date of current station
                date = handles.startdate;
                jd = cal2jd_GT(date(1), date(2), date(3));
                % convert (from julian date) into other formats
                [doy, yyyy] = jd2doy_GT(jd);
                % output date
                yyyy_    = sprintf('%04d',yyyy);
                doy_ 	= sprintf('%03d',doy);
            
                if (yyyy > 2023) || (yyyy == 2023 && doy >= 48)
                    % starting with 17 Feb 2023 long filenames are used (IGSMAIL-8313)
                    % ||| not working
                    tropofile = [Path.DATA 'TROPO/' yyyy_ '/' doy_ '/' 'IGS0OPSFIN_' yyyy_ doy_ '0000_01D_05M_' STAT '_TRO.TRO'];
                else
                    tropofile = [Path.DATA 'TROPO/' yyyy_ '/' doy_ '/' stat doy_ '0.' yyyy_(3:4) 'zpd'];
                end
            
                if exist(tropofile, 'file')
                    % read file
                    tropodata = readTropoFile(tropofile, handles.station);
                    tropodata(:,3) = tropodata(:,3) / 3600;     % convert to [hours]
                    % save into handles
                    handles.IGS_est = tropodata(:,3:4);
                    handles.checkbox_IGS_ZTD.Enable = 'on';
                end
            end
            
            % create plot
            handles = plotTropoGUI(app, handles);
            
            % Choose default command line output for TropoPlot
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_IGS_ZTD
        function checkbox_IGS_ZTD_Callback(app, event)
            % --- Executes on button press in checkbox_IGS_ZTD.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles = plotTropoGUI(app, handles);
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_ZHD_model
        function checkbox_ZHD_model_Callback(app, event)
            % --- Executes on button press in checkbox_ZHD_model.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles = plotTropoGUI(app, handles);
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_ZTD
        function checkbox_ZTD_Callback(app, event)
            % --- Executes on button press in checkbox_ZTD.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles = plotTropoGUI(app, handles);
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_ZTD_model
        function checkbox_ZTD_model_Callback(app, event)
            % --- Executes on button press in checkbox_ZTD_model.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles = plotTropoGUI(app, handles);
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_ZWD_est
        function checkbox_ZWD_est_Callback(app, event)
            % --- Executes on button press in checkbox_ZWD_est.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles = plotTropoGUI(app, handles);
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_ZWD_model
        function checkbox_ZWD_model_Callback(app, event)
            % --- Executes on button press in checkbox_ZWD_model.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles = plotTropoGUI(app, handles);
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_resets
        function checkbox_resets_Callback(app, event)
            % --- Executes on button press in checkbox_resets.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles = plotTropoGUI(app, handles);
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_download
        function pushbutton_download_Callback(app, event)
            % --- Executes on button press in pushbutton_download.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles = load_read_IGS_estimation(app, handles);
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_figure
        function pushbutton_figure_Callback(app, event)
            % --- Executes on button press in pushbutton_figure.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % open figure and print current skyplot there
            fig = figure('Name','Troposphere Plot');
            newAxes = copyobj(handles.axes1, fig);   % copy plot to new figure
            hLegend = findobj(fig, 'Type', 'Legend');
            if ~isempty(handles.leg_txt)        % legend has to be created manually somehow
                legend(handles.leg_txt)
            end
            % add customized datatip
            dcm = datacursormode(fig);
            datacursormode on
            set(dcm, 'updatefcn', @vis_customdatatip_h)
        end

        % Button pushed function: pushbutton_histo
        function pushbutton_histo_Callback(app, event)
            % --- Executes on button press in pushbutton_histo.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if 2 ~= sum([handles.checkbox_ZTD.Value, handles.checkbox_ZHD_model.Value, handles.checkbox_ZWD_model.Value, handles.checkbox_ZWD_est.Value, handles.checkbox_IGS_ZTD.Value, handles.checkbox_ZTD_model.Value])
                fprintf(2,'\nPlease enable exactly two checkboxes!\n');
                return
            end
            histo_data = [];    i = 1;  str_legend = '';
            if handles.checkbox_ZTD.Value
                histo_data(:,1) = handles.zhd_model+handles.zwd_model+handles.zwd_est;
                i = i + 1;  str_legend = [str_legend 'ZTD - '];
            end
            if handles.checkbox_ZTD_model.Value
                histo_data(:,i) = handles.zwd_model + handles.zhd_model;
                i = i + 1;  str_legend = [str_legend 'ZTD_{model} - '];
            end
            if handles.checkbox_ZHD_model.Value
                histo_data(:,i) = handles.zhd_model;
                i = i + 1;  str_legend = [str_legend 'ZHD_{model} - '];
            end
            if handles.checkbox_ZWD_model.Value
                histo_data(:,i) = handles.zwd_model;
                i = i + 1;  str_legend = [str_legend 'ZWD_{model} - '];
            end
            if handles.checkbox_ZWD_est.Value
                histo_data(:,i) = handles.zwd_est;
                i = i + 1;  str_legend = [str_legend 'ZWD - '];
            end
            if handles.checkbox_IGS_ZTD.Value
                IGS_est = interp1(handles.IGS_est(:,1), handles.IGS_est(:,2), handles.h);
                histo_data(:,i) = IGS_est;
                str_legend = [str_legend 'IGS_{ZTD} - '];
            end
            
            % Plot
            diff = histo_data(:,1) - histo_data(:,2);
            diff(isnan(diff)) = [];     % remove NaN values
            figure('Name', ['Troposphere Histogram Plot, ' handles.ff], 'NumberTitle','off');
            histogram(diff, 'Normalization', 'probability')
            
            % Style
            std_trop = std(diff, 'omitnan');
            bias_trop = mean(diff, 'omitnan');
            xlabel(sprintf('std-dev = %2.3f, bias = %2.3f, [m]\n', std_trop, bias_trop))
            xlim(4*[-std_trop-bias_trop std_trop+bias_trop])
            ylabel('[%]')
            yticklabels(yticks*100)
            legend(str_legend(1:end-3))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TropoPlot';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'ZTD = Zenith Total Delay,   ZWD = Zenith Wet Delay,   ZHD = Zenith Hydrostatic Delay';
            app.checkbox_ZTD = uicheckbox(app.figure1);
            app.checkbox_ZTD.ValueChangedFcn = createCallbackFcn(app, @checkbox_ZTD_Callback, true);
            app.checkbox_ZTD.Tag = 'checkbox_ZTD';
            app.checkbox_ZTD.Text = 'ZTD';
            app.checkbox_ZWD_model = uicheckbox(app.figure1);
            app.checkbox_ZWD_model.ValueChangedFcn = createCallbackFcn(app, @checkbox_ZWD_model_Callback, true);
            app.checkbox_ZWD_model.Tag = 'checkbox_ZWD_model';
            app.checkbox_ZWD_model.Text = 'Modeled ZWD';
            app.checkbox_ZWD_est = uicheckbox(app.figure1);
            app.checkbox_ZWD_est.ValueChangedFcn = createCallbackFcn(app, @checkbox_ZWD_est_Callback, true);
            app.checkbox_ZWD_est.Tag = 'checkbox_ZWD_est';
            app.checkbox_ZWD_est.Text = 'Estimated residual ZWD';
            app.checkbox_ZWD_est.Value = true;
            app.checkbox_ZHD_model = uicheckbox(app.figure1);
            app.checkbox_ZHD_model.ValueChangedFcn = createCallbackFcn(app, @checkbox_ZHD_model_Callback, true);
            app.checkbox_ZHD_model.Tag = 'checkbox_ZHD_model';
            app.checkbox_ZHD_model.Text = 'Modeled ZHD';
            app.checkbox_IGS_ZTD = uicheckbox(app.figure1);
            app.checkbox_IGS_ZTD.ValueChangedFcn = createCallbackFcn(app, @checkbox_IGS_ZTD_Callback, true);
            app.checkbox_IGS_ZTD.Tag = 'checkbox_IGS_ZTD';
            app.checkbox_IGS_ZTD.Text = 'IGS ZTD estimation';
            app.pushbutton_figure = uibutton(app.figure1, 'push');
            app.pushbutton_figure.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_figure_Callback, true);
            app.pushbutton_figure.Tag = 'pushbutton_figure';
            app.pushbutton_figure.Text = 'Create figure';
            app.pushbutton_download = uibutton(app.figure1, 'push');
            app.pushbutton_download.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_download_Callback, true);
            app.pushbutton_download.Tag = 'pushbutton_download';
            app.pushbutton_download.Text = 'Download';
            app.checkbox_resets = uicheckbox(app.figure1);
            app.checkbox_resets.ValueChangedFcn = createCallbackFcn(app, @checkbox_resets_Callback, true);
            app.checkbox_resets.Tag = 'checkbox_resets';
            app.checkbox_resets.Text = 'Resets';
            app.checkbox_ZTD_model = uicheckbox(app.figure1);
            app.checkbox_ZTD_model.ValueChangedFcn = createCallbackFcn(app, @checkbox_ZTD_model_Callback, true);
            app.checkbox_ZTD_model.Tag = 'checkbox_ZTD_model';
            app.checkbox_ZTD_model.Text = 'Modeled ZTD';
            app.pushbutton_histo = uibutton(app.figure1, 'push');
            app.pushbutton_histo.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_histo_Callback, true);
            app.pushbutton_histo.Tag = 'pushbutton_histo';
            app.pushbutton_histo.Text = 'Histogram';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TropoPlot_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)TropoPlot_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end